classdef FuzzyLogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AngularPositionGaugeLabel   matlab.ui.control.Label
        AngularPositionGauge        matlab.ui.control.SemicircularGauge
        ManualSliderLabel           matlab.ui.control.Label
        ManualSlider                matlab.ui.control.Slider
        Lamp                        matlab.ui.control.Lamp
        ModeofOperationSwitchLabel  matlab.ui.control.Label
        ModeofOperationSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        pos % Description
        ard_brd % Description
        servo_m % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: ManualSlider
        function ManualSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AngularPositionGauge.Value = changingValue;
        end

        % Value changed function: ModeofOperationSwitch
        function ModeofOperationSwitchValueChanged(app, event)
            value = app.ModeofOperationSwitch.Value;
            
            if strcmp(value, 'Auto')
                app.Lamp.Color = 'g';
                app.ManualSlider.Enable = 'off';
                clear ard_brd   % to clear any previous instance of a

                % Creating an arduino project
                app.ard_brd = arduino('com3', 'uno', 'Libraries', 'Servo');
                
                % Creating servo motor object
                app.servo_m = servo(app.ard_brd, 'D4');
                
                % Read the analog voltages
                val1 = readVoltage(app.ard_brd,'A1');  % value from sensor1 of the panel
                val2 = readVoltage(app.ard_brd,'A2');  % value from sensor2 of the panel
                
                % Convert to corresponding digital voltage using
                % the insternal ADC of Arduino Uno 
                val1 = ardMapFun(val1, 0, 1023, 0, 180);
                val2 = ardMapFun(val2, 0, 1023, 0, 180);
                
                app.pos = 90; % Position of the panel at midday for max light
                
                if val1 > (val2 + 50)
                    if app.pos < 180
                        app.pos = app.pos + 1;
                    end
                    writePosition(app.servo_m, app.pos);   % Moving the servo to the position
                    current_pos = readPosition(app.servo_m);   % Reading the current position
                    app.AngularPositionGauge.Value = current_pos   % Showing the Current position
                    pause(1);
                    
                elseif val2 > (val1 + 50)
                    if app.pos > 0
                        app.pos = app.pos - 1;
                    end
                    writePosition(app.servo_m, app.pos);   % Moving the servo to the position
                    current_pos = readPosition(app.servo_m);   % Reading the current position
                    app.AngularPositionGauge.Value = current_pos   % Showing the Current position
                    pause(1);
                else
                    writePosition(app.servo_m, 0);
                    %fprint('It is NIGHT TIME \n');
                    pause(0.1)
                end
            else
                app.Lamp.Color = 'r';
                app.ManualSlider.Enable = 'on';
                writePosition(app.servo_m, app.pos);
                app.AngularPositionGauge.Value = app.pos;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AngularPositionGaugeLabel = uilabel(app.UIFigure);
            app.AngularPositionGaugeLabel.Text = 'Angular Position';
            app.AngularPositionGauge = uigauge(app.UIFigure, 'semicircular');
            app.AngularPositionGauge.Limits = [0 180];
            app.AngularPositionGauge.MajorTicks = [0 45 90 135 180];
            app.ManualSliderLabel = uilabel(app.UIFigure);
            app.ManualSliderLabel.Text = 'Manual Slider';
            app.ManualSlider = uislider(app.UIFigure);
            app.ManualSlider.Limits = [0 180];
            app.ManualSlider.ValueChangingFcn = createCallbackFcn(app, @ManualSliderValueChanging, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ModeofOperationSwitchLabel = uilabel(app.UIFigure);
            app.ModeofOperationSwitchLabel.Text = 'Mode of Operation';
            app.ModeofOperationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeofOperationSwitch.Items = {'Manual', 'Auto'};
            app.ModeofOperationSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeofOperationSwitchValueChanged, true);
            app.ModeofOperationSwitch.Value = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuzzyLogApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end